classdef OS_FileCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenSimFileCreatorPanel         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        InverseDynamicsTab              matlab.ui.container.Tab
        IDTable                         matlab.ui.control.Table
        CreatemotFileButton             matlab.ui.control.Button
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        SimulationTimesEditField_2Label  matlab.ui.control.Label
        SimulationTimesEditField_2      matlab.ui.control.NumericEditField
        ForwardDynamicsTab              matlab.ui.container.Tab
        ActivationProfileDropDownLabel  matlab.ui.control.Label
        ActivationProfileDropDown       matlab.ui.control.DropDown
        CreatestoFileButton             matlab.ui.control.Button
        SimulationTimesEditFieldLabel   matlab.ui.control.Label
        SimulationTimesEditField        matlab.ui.control.NumericEditField
        GaussianParametersPanel         matlab.ui.container.Panel
        MeanEditFieldLabel              matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        StdDevEditFieldLabel            matlab.ui.control.Label
        StdDevEditField                 matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        RampParametersPanel             matlab.ui.container.Panel
        SlopeEditFieldLabel             matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialize Table
            data = load_csv('sFile','table_default.csv');
            sz = [numel(data.idMuscle) 3];
            varTypes = {'string','string','double'};
            T=table('Size',sz,'VariableTypes',varTypes);
            T(:,1)=data.sMuscle;
            T(:,2)=data.sMuscleFullName;
            app.UITable.Data = T;
            
            % Initialize ID Table
            data = load_csv('sFile','table_default2.csv');
            sz = [numel(data.sDOF) 2];
            varTypes = {'string','single'};
            T2=table('Size',sz,'VariableTypes',varTypes);
            T2(:,1)=data.sDOF;
            T2{:,2}=data.nAngle;
            app.IDTable.Data = T2;
            
            % Initialize Plot
            m = app.MeanEditField.Value;
            s = app.StdDevEditField.Value;
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.001:simEnd;
            y=gaussmf(x,[s m]);
            
            plot(app.UIAxes,x,y);
            
        end

        % Value changed function: ActivationProfileDropDown
        function ActivationProfileDropDownValueChanged(app, event)
            f = app.ActivationProfileDropDown.Value;
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.001:simEnd;
            
            switch f
                case 'Gaussian'
                    % Panel Display
                    app.GaussianParametersPanel.Visible = 1;
                    app.RampParametersPanel.Visible = 0;
                    
                    % Plot
                    m = app.MeanEditField.Value;
                    s = app.StdDevEditField.Value;
                    y=gaussmf(x,[s m]);
                    
                    plot(app.UIAxes,x,y);
                    
                case 'Ramp'
                    app.GaussianParametersPanel.Visible = 0;
                    app.RampParametersPanel.Visible = 1;
                    
                    % Plot
                    
                    b = app.SlopeEditField.Value;
                    y = b*x;
                    
                    plot(app.UIAxes,x,y);
                    
            end
            
        end

        % Button pushed function: CreatestoFileButton
        function CreatestoFileButtonPushed(app, event)
            f = app.ActivationProfileDropDown.Value;
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.001:simEnd;
            nMax = app.UITable.Data{:,3};
            sMuscle = app.UITable.Data{:,1};
            filename = app.FileNameEditField.Value;
            
            switch f
                case 'Gaussian'
                    
                    % Plot
                    m = app.MeanEditField.Value;
                    s = app.StdDevEditField.Value;
                    
                    y=gaussmf(x,[s m]);
                    
                    nAct = y.*nMax;
                    inputOSactivation(x,nAct,sMuscle,'filename',filename);
                    
                case 'Ramp'
                    
                    % Plot
                    
                    b = app.SlopeEditField.Value;
                    y = b*x;
                    
                    nAct = y.*nMax;
                    inputOSactivation(x,nAct,sMuscle,'filename',filename);
                    
            end
        end

        % Value changed function: MeanEditField
        function MeanEditFieldValueChanged(app, event)
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.001:simEnd;
            m = app.MeanEditField.Value;
            s = app.StdDevEditField.Value;
            y=gaussmf(x,[s m]);
            
            plot(app.UIAxes,x,y);
            
        end

        % Value changed function: StdDevEditField
        function StdDevEditFieldValueChanged(app, event)
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.01:simEnd;
            m = app.MeanEditField.Value;
            s = app.StdDevEditField.Value;
            y=gaussmf(x,[s m]);
            
            plot(app.UIAxes,x,y);
            
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.001:simEnd;
            b = app.SlopeEditField.Value;
            y = b*x;
            
            plot(app.UIAxes,x,y);
            
        end

        % Value changed function: SimulationTimesEditField
        function SimulationTimesEditFieldValueChanged(app, event)
            f = app.ActivationProfileDropDown.Value;
            simEnd = app.SimulationTimesEditField.Value;
            x=0:0.001:simEnd;
            
            switch f
                case 'Gaussian'
                    
                    % Plot
                    m = app.MeanEditField.Value;
                    s = app.StdDevEditField.Value;
                    y=gaussmf(x,[s m]);
                    
                    plot(app.UIAxes,x,y);
                    
                case 'Ramp'
                    
                    % Plot
                    
                    b = app.SlopeEditField.Value;
                    y = b*x;
                    
                    plot(app.UIAxes,x,y);
                    
            end
            
        end

        % Button pushed function: CreatemotFileButton
        function CreatemotFileButtonPushed(app, event)

            simEnd = app.SimulationTimesEditField_2.Value;
            x=0:0.001:simEnd;
            nKin = app.IDTable.Data{:,2};
            sDOF = app.IDTable.Data{:,1};
            filename = app.FileNameEditField_2.Value;
            
            nMotion = ones(1,numel(x)).*nKin;
            inputOSmotion(x,nMotion,sDOF,'filename',filename);
                    

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OpenSimFileCreatorPanel = uipanel(app.UIFigure);
            app.OpenSimFileCreatorPanel.Title = 'OpenSim File Creator';
            app.TabGroup = uitabgroup(app.OpenSimFileCreatorPanel);
            app.InverseDynamicsTab = uitab(app.TabGroup);
            app.InverseDynamicsTab.Title = 'Inverse Dynamics';
            app.IDTable = uitable(app.InverseDynamicsTab);
            app.IDTable.ColumnName = {'Degree of Freedom'; 'Angle (deg)'};
            app.IDTable.RowName = {};
            app.CreatemotFileButton = uibutton(app.InverseDynamicsTab, 'push');
            app.CreatemotFileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemotFileButtonPushed, true);
            app.CreatemotFileButton.Text = 'Create mot File';
            app.FileNameEditField_2Label = uilabel(app.InverseDynamicsTab);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.InverseDynamicsTab, 'text');
            app.FileNameEditField_2.Value = 'Output';
            app.SimulationTimesEditField_2Label = uilabel(app.InverseDynamicsTab);
            app.SimulationTimesEditField_2Label.Text = 'Simulation Time (s)';
            app.SimulationTimesEditField_2 = uieditfield(app.InverseDynamicsTab, 'numeric');
            app.SimulationTimesEditField_2.Value = 10;
            app.ForwardDynamicsTab = uitab(app.TabGroup);
            app.ForwardDynamicsTab.Title = 'Forward Dynamics';
            app.ActivationProfileDropDownLabel = uilabel(app.ForwardDynamicsTab);
            app.ActivationProfileDropDownLabel.Text = 'Activation Profile';
            app.ActivationProfileDropDown = uidropdown(app.ForwardDynamicsTab);
            app.ActivationProfileDropDown.Items = {'Gaussian', 'Ramp'};
            app.ActivationProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ActivationProfileDropDownValueChanged, true);
            app.ActivationProfileDropDown.Value = 'Gaussian';
            app.CreatestoFileButton = uibutton(app.ForwardDynamicsTab, 'push');
            app.CreatestoFileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatestoFileButtonPushed, true);
            app.CreatestoFileButton.Text = 'Create sto File';
            app.SimulationTimesEditFieldLabel = uilabel(app.ForwardDynamicsTab);
            app.SimulationTimesEditFieldLabel.Text = 'Simulation Time (s)';
            app.SimulationTimesEditField = uieditfield(app.ForwardDynamicsTab, 'numeric');
            app.SimulationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimesEditFieldValueChanged, true);
            app.SimulationTimesEditField.Value = 1;
            app.GaussianParametersPanel = uipanel(app.ForwardDynamicsTab);
            app.GaussianParametersPanel.Title = 'Gaussian Parameters';
            app.MeanEditFieldLabel = uilabel(app.GaussianParametersPanel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.GaussianParametersPanel, 'numeric');
            app.MeanEditField.ValueChangedFcn = createCallbackFcn(app, @MeanEditFieldValueChanged, true);
            app.MeanEditField.Value = 0.5;
            app.StdDevEditFieldLabel = uilabel(app.GaussianParametersPanel);
            app.StdDevEditFieldLabel.Text = 'Std Dev';
            app.StdDevEditField = uieditfield(app.GaussianParametersPanel, 'numeric');
            app.StdDevEditField.ValueChangedFcn = createCallbackFcn(app, @StdDevEditFieldValueChanged, true);
            app.StdDevEditField.Value = 0.2;
            app.UIAxes = uiaxes(app.ForwardDynamicsTab);
            app.RampParametersPanel = uipanel(app.ForwardDynamicsTab);
            app.RampParametersPanel.Title = 'Ramp Parameters';
            app.RampParametersPanel.Visible = 'off';
            app.SlopeEditFieldLabel = uilabel(app.RampParametersPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.RampParametersPanel, 'numeric');
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.SlopeEditField.Value = 0.5;
            app.FileNameEditFieldLabel = uilabel(app.ForwardDynamicsTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ForwardDynamicsTab, 'text');
            app.FileNameEditField.Value = 'Output';
            app.UITable = uitable(app.ForwardDynamicsTab);
            app.UITable.ColumnName = {'sMuscle'; 'sMuscleFullName'; 'nActivationMax'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
        end
    end

    methods (Access = public)

        % Construct app
        function app = OS_FileCreator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end